classdef Cubes_To_Trees < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        Step1                          matlab.ui.container.Panel
        ParentFolderBrowse             matlab.ui.control.Button
        SingleCellsFolder              matlab.ui.control.EditField
        text2                          matlab.ui.control.Label
        text1                          matlab.ui.control.Label
        Execute                        matlab.ui.control.Button
        Step2                          matlab.ui.container.Panel
        Samples                        matlab.ui.container.Panel
        Folders                        matlab.ui.control.Label
        uipanel15                      matlab.ui.container.Panel
        text49                         matlab.ui.control.Label
        text48                         matlab.ui.control.Label
        NoOfCores                      matlab.ui.control.EditField
        text47                         matlab.ui.control.Label
        text46                         matlab.ui.control.Label
        text45                         matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        ReconstructionParametersPanel  matlab.ui.container.Panel
        text51                         matlab.ui.control.Label
        ThresholdMax                   matlab.ui.control.EditField
        text71                         matlab.ui.control.Label
        ThresholdMin                   matlab.ui.control.EditField
        text72                         matlab.ui.control.Label
        ThresholdSteps                 matlab.ui.control.EditField
        text51_2                       matlab.ui.control.Label
        MPDMax                         matlab.ui.control.EditField
        text71_2                       matlab.ui.control.Label
        MPDMin                         matlab.ui.control.EditField
        text72_2                       matlab.ui.control.Label
        MPDSteps                       matlab.ui.control.EditField
        text72_3                       matlab.ui.control.Label
        BalancingFactor                matlab.ui.control.Slider
        text72_4                       matlab.ui.control.Label
        SomaSpacing                    matlab.ui.control.EditField
        VoxelResolutionPanel           matlab.ui.container.Panel
        text64                         matlab.ui.control.Label
        VoxelX                         matlab.ui.control.EditField
        text68                         matlab.ui.control.Label
        VoxelY                         matlab.ui.control.EditField
        text70                         matlab.ui.control.Label
        VoxelZ                         matlab.ui.control.EditField
        text72_5                       matlab.ui.control.Label
        Lcoordinates                   matlab.ui.control.CheckBox
    end

    
    methods (Access = private)

        
        function Threads_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to Threads (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Soma_to_Cube_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to Soma_to_Cube (see VARARGIN)
%             % Choose default command line output for Soma_to_Cube
%             app.output = hObject;
%             addpath(pwd);
%             % Update handles structure
%             guidata(hObject, handles);
             
        end

        % Button pushed function: Execute
        function Execute_Callback(app, event)
           
            VoxelR = [str2num(get(app.VoxelX,'value')) str2num(get(app.VoxelY,'value')) str2num(get(app.VoxelZ,'value'))];
            Threshold = [str2num(get(app.ThresholdMin,'value')):str2num(get(app.ThresholdSteps,'value')):str2num(get(app.ThresholdMax,'value'))];
            MPD = [str2num(get(app.MPDMin,'value')):str2num(get(app.MPDSteps,'value')):str2num(get(app.MPDMax,'value'))];
            Folder = get(app.SingleCellsFolder,'value');
            BalancingF = get(app.BalancingFactor,'value');
            SomaSpace = str2num(get(app.SomaSpacing,'value'));
            clc
            addpath(pwd);
            LCoordinates = get(app.Lcoordinates,'Value');
            Threads = str2num(get(app.NoOfCores,'value'));
            PathToTrees = mfilename('fullpath');

            SepInd = strfind(PathToTrees,filesep);
            addpath([PathToTrees(1:SepInd(end)),'TREES1.17']);
            addpath(genpath('~/'))
            try
                ReconstructionMaster_v1_0(Folder,Threshold,MPD,BalancingF,SomaSpace,VoxelR,Threads,LCoordinates)
            catch me
                msgbox(['Something went wrong, Check that all parameters are filled in correctly. Error in function - ',me.stack(1).name,'.    Line - ',num2str(me.stack(1).line),'.   ',me.message])
            end
        end

        % Callback function
        function ImageShow_Callback(app, event)
            % hObject    handle to ImageShow (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
          
        end

        % Button pushed function: ParentFolderBrowse
        function ParentFolderBrowse_Callback(app, event)
            % hObject    handle to ParentFolderBrowse (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Folder = uigetdir(cd,'MATLAB Root Directory');
            set(app.SingleCellsFolder,'value',Folder);
            import java.lang.*;
            r=Runtime.getRuntime;
            ncpu=r.availableProcessors;
            Directories = dir(Folder);
            DirectoryIndex = find([Directories.isdir])';
            valueDir = ['1) ',Directories(DirectoryIndex(3)).name];
            for i = 1:length(DirectoryIndex)-3
            valueDir = [valueDir newline num2str(i+1),') ',Directories(DirectoryIndex(i+3)).name];
            end
            set(app.Folders, 'text', valueDir);
            set(app.text47, 'text', num2str(ncpu/2));
        end

        % Callback function
        function sliderThreshold_Callback(app, event)
            % hObject    handle to sliderThreshold (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
          
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Cubes to Trees v1.0';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.Step1 = uipanel(app.figure1);
            app.ParentFolderBrowse = uibutton(app.Step1, 'push');
            app.ParentFolderBrowse.ButtonPushedFcn = createCallbackFcn(app, @ParentFolderBrowse_Callback, true);
            app.ParentFolderBrowse.Text = 'Browse Folder';
            app.SingleCellsFolder = uieditfield(app.Step1, 'text');
            app.text2 = uilabel(app.Step1);
            app.text2.Text = 'Select "SingleCells" Parent directory: ';
            app.text1 = uilabel(app.figure1);
            app.text1.Text = 'Trees-Based Reconstruction, 2020 v1.0, Pop-Rec Step 2';
            app.Execute = uibutton(app.figure1, 'push');
            app.Execute.ButtonPushedFcn = createCallbackFcn(app, @Execute_Callback, true);
            app.Execute.Text = 'Start';
            app.Step2 = uipanel(app.figure1);
            app.Samples = uipanel(app.Step2);
            app.Samples.Title = 'Folders';
            app.Folders = uilabel(app.Samples);
            app.Folders.Text = '';
            app.uipanel15 = uipanel(app.figure1);
            app.uipanel15.Title = 'Parallel Processing';
            app.text49 = uilabel(app.uipanel15);
            app.text49.Text = 'Use';
            app.text48 = uilabel(app.uipanel15);
            app.text48.Text = 'out of ';
            app.NoOfCores = uieditfield(app.uipanel15, 'text');
            app.NoOfCores.Value = '0';
            app.text47 = uilabel(app.uipanel15);
            app.text47.Text = '';
            app.text46 = uilabel(app.uipanel15);
            app.text46.Text = 'cores';
            app.text45 = uilabel(app.uipanel15);
            app.text45.Text = '*Select 0 for non-parallel processing';
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = 'Cubes_To_Trees-01.png';
            app.ReconstructionParametersPanel = uipanel(app.figure1);
            app.ReconstructionParametersPanel.Title = 'Reconstruction Parameters';
            app.text51 = uilabel(app.ReconstructionParametersPanel);
            app.text51.Text = 'Steps';
            app.ThresholdMax = uieditfield(app.ReconstructionParametersPanel, 'text');
            app.ThresholdMax.Value = '101';
            app.text71 = uilabel(app.ReconstructionParametersPanel);
            app.text71.Text = 'Max';
            app.ThresholdMin = uieditfield(app.ReconstructionParametersPanel, 'text');
            app.ThresholdMin.Value = '1';
            app.text72 = uilabel(app.ReconstructionParametersPanel);
            app.text72.Text = 'Threshold -------------- Min';
            app.ThresholdSteps = uieditfield(app.ReconstructionParametersPanel, 'text');
            app.ThresholdSteps.Value = '20';
            app.text51_2 = uilabel(app.ReconstructionParametersPanel);
            app.text51_2.Text = 'Steps';
            app.MPDMax = uieditfield(app.ReconstructionParametersPanel, 'text');
            app.MPDMax.Value = '12';
            app.text71_2 = uilabel(app.ReconstructionParametersPanel);
            app.text71_2.Text = 'Max';
            app.MPDMin = uieditfield(app.ReconstructionParametersPanel, 'text');
            app.MPDMin.Value = '6';
            app.text72_2 = uilabel(app.ReconstructionParametersPanel);
            app.text72_2.Text = 'Max Point Dist -------- Min';
            app.MPDSteps = uieditfield(app.ReconstructionParametersPanel, 'text');
            app.MPDSteps.Value = '3';
            app.text72_3 = uilabel(app.ReconstructionParametersPanel);
            app.text72_3.Text = 'Balancing Factor';
            app.BalancingFactor = uislider(app.ReconstructionParametersPanel);
            app.BalancingFactor.Limits = [0 1];
            app.BalancingFactor.Value = 0.5;
            app.text72_4 = uilabel(app.ReconstructionParametersPanel);
            app.text72_4.Text = 'Soma Spacing';
            app.SomaSpacing = uieditfield(app.ReconstructionParametersPanel, 'text');
            app.SomaSpacing.Value = '50';
            app.VoxelResolutionPanel = uipanel(app.ReconstructionParametersPanel);
            app.VoxelResolutionPanel.Title = 'Voxel Resolution';
            app.text64 = uilabel(app.VoxelResolutionPanel);
            app.text64.Text = 'X';
            app.VoxelX = uieditfield(app.VoxelResolutionPanel, 'text');
            app.VoxelX.Value = '1';
            app.text68 = uilabel(app.VoxelResolutionPanel);
            app.text68.Text = 'Y';
            app.VoxelY = uieditfield(app.VoxelResolutionPanel, 'text');
            app.VoxelY.Value = '1';
            app.text70 = uilabel(app.VoxelResolutionPanel);
            app.text70.Text = 'Z';
            app.VoxelZ = uieditfield(app.VoxelResolutionPanel, 'text');
            app.VoxelZ.Value = '1';
            app.text72_5 = uilabel(app.ReconstructionParametersPanel);
            app.text72_5.Text = 'Local Coord''';
            app.Lcoordinates = uicheckbox(app.ReconstructionParametersPanel);
            app.Lcoordinates.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cubes_To_Trees

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Soma_to_Cube_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end