classdef ReconstructionValidation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PathtoVaa3DexefileEditField  matlab.ui.control.EditField
        PathtoVaa3DexefileEditFieldLabel  matlab.ui.control.Label
        ButtonVaa3D                  matlab.ui.control.Button
        SamplesTextArea              matlab.ui.control.TextArea
        SamplesTextAreaLabel         matlab.ui.control.Label
        ofNeuronsSampleforTrainingSpinner  matlab.ui.control.Spinner
        ofNeuronsSampleforTrainingSpinnerLabel  matlab.ui.control.Label
        BrowseButton                 matlab.ui.control.Button
        GoButton                     matlab.ui.control.Button
        CompletedGauge               matlab.ui.control.LinearGauge
        CompletedLabel               matlab.ui.control.Label
        ReconstructionValidationAppPopRecStep3NadavYayon2021ver10Label  matlab.ui.control.Label
        SomaToCubeoutputdirectoryEditField  matlab.ui.control.EditField
        SomaToCubeoutputdirectoryEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            Folder = uigetdir(cd,'MATLAB Root Directory');
            set(app.SomaToCubeoutputdirectoryEditField,'value',Folder);
            Directories = dir(Folder);
            DirectoryIndex = find([Directories.isdir])';
            valueDir = ['1)  ',Directories(DirectoryIndex(3)).name];
            for i = 1:length(DirectoryIndex)-3
                valueDir = [valueDir newline num2str(i+1),') ',Directories(DirectoryIndex(i+3)).name];
            end
            set(app.SamplesTextArea, 'value', valueDir);
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
             CellsFromEachSample = get(app.ofNeuronsSampleforTrainingSpinner, 'value');
             Path = get(app.SomaToCubeoutputdirectoryEditField, 'value');
             Vaa3DPath = get(app.PathtoVaa3DexefileEditField, 'value');
             Reconstruction_Validation_V1_1(Path,CellsFromEachSample,app.CompletedGauge,Vaa3DPath);
        end

        % Button pushed function: ButtonVaa3D
        function ButtonVaa3DPushed(app, event)
             Folder = uigetdir(cd,'MATLAB Root Directory');
             set(app.PathtoVaa3DexefileEditField,'value',Folder);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SomaToCubeoutputdirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.SomaToCubeoutputdirectoryEditFieldLabel.Text = 'Soma To Cube output directory:';
            app.SomaToCubeoutputdirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.ReconstructionValidationAppPopRecStep3NadavYayon2021ver10Label = uilabel(app.UIFigure);
            app.ReconstructionValidationAppPopRecStep3NadavYayon2021ver10Label.Text = 'Reconstruction Validation App, Pop-Rec Step 3, Nadav Yayon 2021, ver 1.0';
            app.CompletedLabel = uilabel(app.UIFigure);
            app.CompletedLabel.Text = 'Completed %';
            app.CompletedGauge = uigauge(app.UIFigure, 'linear');
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ofNeuronsSampleforTrainingSpinnerLabel = uilabel(app.UIFigure);
            app.ofNeuronsSampleforTrainingSpinnerLabel.Text = '# of Neurons/Sample for Training';
            app.ofNeuronsSampleforTrainingSpinner = uispinner(app.UIFigure);
            app.ofNeuronsSampleforTrainingSpinner.Step = 5;
            app.ofNeuronsSampleforTrainingSpinner.Limits = [5 100];
            app.ofNeuronsSampleforTrainingSpinner.Value = 10;
            app.SamplesTextAreaLabel = uilabel(app.UIFigure);
            app.SamplesTextAreaLabel.Text = 'Samples';
            app.SamplesTextArea = uitextarea(app.UIFigure);
            app.ButtonVaa3D = uibutton(app.UIFigure, 'push');
            app.ButtonVaa3D.ButtonPushedFcn = createCallbackFcn(app, @ButtonVaa3DPushed, true);
            app.ButtonVaa3D.Text = 'Browse';
            app.PathtoVaa3DexefileEditFieldLabel = uilabel(app.UIFigure);
            app.PathtoVaa3DexefileEditFieldLabel.Text = 'Path to Vaa3D exe file';
            app.PathtoVaa3DexefileEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReconstructionValidation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end